classdef BeamPrediction_AfterEval < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TransducerDesignSettingsPanel  matlab.ui.container.Panel
        TransducerShapeDropDownLabel   matlab.ui.control.Label
        TransducerShapeDropDown        matlab.ui.control.DropDown
        SimulateButton                 matlab.ui.control.Button
        TRIANGLEPanel                  matlab.ui.container.Panel
        x1EditFieldLabel               matlab.ui.control.Label
        x1EditField                    matlab.ui.control.NumericEditField
        y1EditFieldLabel               matlab.ui.control.Label
        y1EditField                    matlab.ui.control.NumericEditField
        x2EditFieldLabel               matlab.ui.control.Label
        x2EditField                    matlab.ui.control.NumericEditField
        y2EditFieldLabel               matlab.ui.control.Label
        y2EditField                    matlab.ui.control.NumericEditField
        x3EditFieldLabel               matlab.ui.control.Label
        x3EditField                    matlab.ui.control.NumericEditField
        y3EditFieldLabel               matlab.ui.control.Label
        y3EditField                    matlab.ui.control.NumericEditField
        Vertex1Label                   matlab.ui.control.Label
        Vertex2Label                   matlab.ui.control.Label
        Vertex3Label                   matlab.ui.control.Label
        ARRAYELEMENTSPanel             matlab.ui.container.Panel
        noofelementsEditFieldLabel     matlab.ui.control.Label
        noofelementsEditField          matlab.ui.control.NumericEditField
        LinearCheckBox                 matlab.ui.control.CheckBox
        MatrixCheckBox                 matlab.ui.control.CheckBox
        FOCALSIZEPanel                 matlab.ui.container.Panel
        LengthEditFieldLabel           matlab.ui.control.Label
        LengthEditField                matlab.ui.control.NumericEditField
        DCheckBox                      matlab.ui.control.CheckBox
        DCheckBox_2                    matlab.ui.control.CheckBox
        CIRCLEPanel                    matlab.ui.container.Panel
        DiameterEditFieldLabel         matlab.ui.control.Label
        DiameterEditField              matlab.ui.control.NumericEditField
        RECTANGLEPanel                 matlab.ui.container.Panel
        xEditField_3Label              matlab.ui.control.Label
        recEditField                   matlab.ui.control.NumericEditField
        yEditFieldLabel                matlab.ui.control.Label
        yEditField                     matlab.ui.control.NumericEditField
        SQUAREPanel                    matlab.ui.container.Panel
        xEditField_2Label              matlab.ui.control.Label
        squEditField                   matlab.ui.control.NumericEditField
        FrequencyValueEditFieldLabel   matlab.ui.control.Label
        FrequencyValueEditField        matlab.ui.control.NumericEditField
        kHzLabel                       matlab.ui.control.Label
        UsageDropDownLabel             matlab.ui.control.Label
        UsageDropDown                  matlab.ui.control.DropDown
        STEP1ChooseaTransducerShapeLabel  matlab.ui.control.Label
        STEP2ChooseSizeofTransducerLabel  matlab.ui.control.Label
        ArrayCheckBox                  matlab.ui.control.CheckBox
        FocalCheckBox                  matlab.ui.control.CheckBox
        STEP3ChooseaFrequencyLabel     matlab.ui.control.Label
        STEP4ChooseaPhaseAngleLabel    matlab.ui.control.Label
        PhaseAngleDropDownLabel        matlab.ui.control.Label
        PhaseAngleDropDown             matlab.ui.control.DropDown
        Label                          matlab.ui.control.Label
        TransducerDesignLabel          matlab.ui.control.Label
        FocalTransducerDesignLabel     matlab.ui.control.Label
        Panel                          matlab.ui.container.Panel
        ShowAxesButton                 matlab.ui.control.Button
        transducer_design              matlab.ui.control.UIAxes
        FullBeamSimulationLabel        matlab.ui.control.Label
        fullbeam_simulation            matlab.ui.control.UIAxes
        focal_transducer_design        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        mesh_size; % transducer mesh size
        x_size; % x size of transducer
        y_size; % y size of transducer
        shape = 'Square'; % selected shape of transducer
        array_elements = 1; % the amount of elements
        vertex = zeros(3,2); % the triangle coords
        focal; % focal length
        tri_points;
        frequency;
        speed = 1530;
        spacing;
        phase = 1;
    end
    
    methods (Access = private)
        
        function popupCallback(app, event)
            app.tri_points = event.SelectedOption;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            tic
            clear mag1 phi1 singleT
            cla(app.focal_transducer_design)
            app.FocalTransducerDesignLabel.Visible = false;
            
            switch app.shape
                case {'Square'}
                    xlength = app.x_size;       % Length of the x side
                    
                    wavelength = app.speed/app.frequency;
                    app.mesh_size = wavelength/8;       % Assigning the mesh size - 16 elements = 10:44, 8 elements = 7:59, 4 elements = 7:24, 2 elements = 6:55
                    
                    ymin=-(xlength)*0.5;        % Getting the minimum of y - multiply by 0.5 for half the length of y
                    ymax=abs(ymin);             % Getting the maximum of y - absolute value of ymin
                    xmin=ymin;xmax=ymax;
                    
                    Tpointsx=xmin:app.mesh_size:xmax;      % Array of xmin to xmax going up in steps of the mesh size
                    %Tpointsx=Tpointsx(1:end-(app.array_elements-1));
                    Tpointsy=ymin:app.mesh_size:ymax;      % Array of ymin to ymax going up in steps of the mesh size
                    %Tpointsy=Tpointsy(1:end-(app.array_elements-1));
                    
                    if (app.array_elements > 1 && app.MatrixCheckBox.Value)
                        singleT = transducerArray(xlength,Tpointsx,wavelength,app.array_elements,app.mesh_size,2);
                        mag1 = repmat(singleT, app.array_elements);
                    elseif (app.array_elements > 1 && app.LinearCheckBox.Value)
                        singleT = transducerArray(xlength,Tpointsx,wavelength,app.array_elements,app.mesh_size,1);
                        mag1 = repmat(singleT,1,app.array_elements);
                    else 
                        mag1=ones(length(Tpointsx),length(Tpointsy));
                    end

                    phi1=zeros(length(Tpointsx),length(Tpointsy));      % Matrix of zeros - size of x array * y array
                case {'Rectangle'}
                    xlength = app.x_size;
                    ylength = app.y_size;
                    
                    wavelength = app.speed/app.frequency;
                    app.mesh_size = wavelength/8;     % Assigning the mesh size - 16 elements = 11:50, 8 elements = 9:13, 4 elements = 7:50, 2 elements = 7:19
                    
                    ymin=-(ylength)*.5;
                    ymax=abs(ymin);
                    xmin=-(xlength)*.5;
                    xmax=abs(xmin);
                    
                    Tpointsx=xmin:app.mesh_size:xmax;
                    Tpointsy=Tpointsx;
                    
                    mag1=zeros(length(Tpointsx),length(Tpointsy));
                    phi1=zeros(length(Tpointsx),length(Tpointsy));
                    indy=0;
                    
                    for y=ymin:app.mesh_size:ymax
                        indy=indy+1;
                        indx=0;
                        for x=xmin:app.mesh_size:xmax
                            indx=indx+1;
                            if (x*y)<(xlength*ylength)
                                mag1(indx,indy)=1;
                            end
                        end
                    end
                case {'Circle'}
                    diameter = app.x_size;
                    
                    wavelength = app.speed/app.frequency;
                    app.mesh_size = wavelength/8;      % Assigning the mesh size - 16 elements = 12:34, 8 elements = 8:13, 4 elements = 7:48, 2 elements = 6:55
                    
                    ymin=-(diameter)*.5;
                    ymax=abs(ymin);
                    
                    xmin=ymin;xmax=ymax;
                    
                    Tpointsx=xmin:app.mesh_size:xmax;
                    Tpointsy=ymin:app.mesh_size:ymax;
                    
                    phi1=zeros(length(Tpointsx),length(Tpointsy));
                    indy=0;
                    
                    for y=ymin:app.mesh_size:ymax
                        indy=indy+1;
                        indx=0;
                        for x=xmin:app.mesh_size:xmax
                            indx=indx+1;
                            if sqrt((x^2)+(y^2))<(diameter/2)
                                mag1(indx,indy)=1;
                            else
                                mag1(indx,indy)=0;
                            end
                        end
                    end
                case {'Triangle'}
                    wavelength = app.speed/app.frequency;
                    app.mesh_size = wavelength/8;
                    
                    fig = uifigure('Name','Triangle Points');
                    ax = UIAxes(fig,'Position',[10 10 550 400]);
                    plot(ax,app.vertex(:,1),app.vertex(:,2),'x');
                    
                    confirm = uiconfirm(app.UIFigure,'Are the points of the triangle displayed in the figure correct?','Confirm Trinagle Points','CloseFcn',@(src,event)popupCallback(app,event),'Options',{'Yes','No'});
                    waitfor(confirm);
                    
                    if (strcmp(app.tri_points, 'Yes'))
                        s=det([app.vertex(1,:)-app.vertex(2,:);app.vertex(3,:)-app.vertex(1,:)]);
                        
                        indy=0;
                        indx=0;
                        
                        ymin=min(app.vertex(:,2))*1.2;
                        ymax=max(app.vertex(:,2))*1.2;
                        xmin=min(app.vertex(:,1))*1.2;
                        xmax=max(app.vertex(:,1))*1.2;
                        
                        Tpointsx=xmin:app.mesh_size:xmax;
                        Tpointsy=ymin:app.mesh_size:ymax;
                        
                        phi1=zeros(length(Tpointsx),length(Tpointsy));
                        
                        for y=ymin:app.mesh_size:ymax
                            indy=indy+1;
                            for x=xmin:app.mesh_size:xmax
                                indx=indx+1;
                                if s*det([app.vertex(3,:)-[x,y];app.vertex(2,:)-app.vertex(3,:)])>=0 && s*det([app.vertex(1,:)-[x,y];app.vertex(3,:)-app.vertex(1,:)])>=0 && s*det([app.vertex(2,:)-[x,y];app.vertex(1,:)-app.vertex(2,:)])>=0
                                    mag1(indx,indy)=1;
                                else
                                    mag1(indx,indy)=0;
                                end
                            end
                            indx=0;
                        end
                    else
                        uiconfirm(app.UIFigure,'Re-Enter the triangle points again','Enter Points Again','Options',{'Ok'},'Icon','info');
                        return;
                    end
            end
            
            LengthX = length(Tpointsx);     % Storing the size of the x array
            LengthY = length(Tpointsy);     % Storing the size of the y array
            
            TpointsxMat = repmat(Tpointsx',1,LengthY);      % Repeats x array (transposed) for [1 * length of y] matrix
            TpointsyMat = repmat(Tpointsy,LengthX,1);       % Repeats y array for [length of x * 1] matrix
            
            % approach to focussing that puts a geometric focus on the device
            % only considers focussing on axis and not arbitrary point
            % in the field
            if (app.FocalCheckBox.Value)
                d=sqrt(Tpointsx.^2+app.focal^2);
                
                switch app.phase
                    case 1
                        if (app.DCheckBox.Value)
                            Tpointsz=d-max(d);
                            TpointszMat = repmat(Tpointsz,LengthX,1);
                        elseif (app.DCheckBox_2.Value)
                            TpointszMat =  mag1.*(sqrt(d.*d')-max(d));
                            TpointszMat = ( TpointszMat - min(TpointszMat (:,1)) );
                        end
                    case 2 
                        opp = tan(15*(pi/180))*app.focal;
                        d = sqrt((Tpointsx-opp).^2+app.focal^2);
                        
                        if (app.DCheckBox.Value)
                            Tpointsz=d-max(d);  
                            TpointszMat = repmat(Tpointsz,LengthX,1);
                        elseif (app.DCheckBox_2.Value)
                            TpointszMat =  mag1.*(sqrt(d.*d')-max(d));
                            TpointszMat = ( TpointszMat - min(TpointszMat (:,1)) );
                        end
                    case 3
                        opp = tan((-15)*(pi/180))*app.focal;
                        d = sqrt((Tpointsx-opp).^2+app.focal^2);
                        
                        if (app.DCheckBox.Value)
                            Tpointsz=d-max(d);
                            TpointszMat = repmat(Tpointsz,LengthX,1);
                        elseif (app.DCheckBox_2.Value)
                            TpointszMat =  mag1.*(sqrt(d.*d')-max(d));
                            TpointszMat = ( TpointszMat - min(TpointszMat (:,1)) );
                        end
                end
                
                mesh(app.transducer_design,Tpointsx,Tpointsy,mag1);
                mesh(app.focal_transducer_design,Tpointsx,Tpointsy,TpointszMat);
                app.FocalTransducerDesignLabel.Visible = true;
            else
                Tpointsz=zeros(1,LengthX);     % Zero matrix of 1 * length of x array
                TpointszMat = repmat(Tpointsz,LengthX,1);       % Repeats zero matrix from above for length of x array * 1
                
                size(mag1), LengthX, LengthY
                figure();
                imagesc(mag1);
                
                mesh(app.transducer_design, Tpointsx,Tpointsy,mag1);
            end
            
            Tpoints1=[reshape(TpointsxMat,LengthX * LengthY,1) reshape(TpointsyMat,LengthX * LengthY,1) reshape(TpointszMat,LengthX * LengthY,1)];
            
            % Need to think about the field points for mesh change and
            % slice
            % input the required field dimensions
            Fpointsy=ymin*1.5:app.mesh_size:ymax*1.5;%.1e-3:ymax*1.5;0:szstep:ymax*1.5
            Fpointsz=1e-3:.5e-3:200e-3;
%             Fpointsz(length(Fpointsz)/2:length(Fpointsz)) = 0;
%             Fpointsy(length(Fpointsy)/2:length(Fpointsy)) = 0;
            LengthY2 = length(Fpointsy);
            LengthZ2 = length(Fpointsz);
            FpointsyMat = repmat(Fpointsy',1,LengthZ2);
            FpointszMat = repmat(Fpointsz,LengthY2,1);
            Fpoints1=[(zeros(LengthY2 * LengthZ2,1)) reshape(FpointsyMat,LengthY2 * LengthZ2,1) reshape(FpointszMat,LengthY2 * LengthZ2,1) ];
            
            phi1_1=reshape(phi1,length(Tpointsx)*length(Tpointsy),1);
            mag1_1=reshape(mag1,length(Tpointsx)*length(Tpointsy),1);
            real=mag1_1.*cos(phi1_1);
            imag=mag1_1.*sin(phi1_1);
            s_2=complex(real,imag);
            
           
            data=fullbeam3D_looped(Tpoints1,Fpoints1,app.frequency,s_2,app.speed,1000);
            data2=reshape(data,length(Fpointsy),length(Fpointsz));
            mesh(app.fullbeam_simulation , Fpointsz,Fpointsy,20*log10(abs(data2)/max(max(abs(data2)))));
            toc
%             pathout=pwd;
%             fig = figure();
%             ax = uiaxes(fig);
%             
%             mesh(ax, Fpointsz,Fpointsy,20*log10(abs(data2)/max(max(abs(data2)))));
%             view(0,90)
%             
%             freqn=app.frequency/1e3;
%             fieldimgout=sprintf('%s%s%i%s','4field_',app.shape,app.frequency,'kHz.fig');
%             saveas(fig,fullfile(pathout,fieldimgout));
%             dataout=sprintf('%s%s%i%s','field_',app.shape,freqn,'kHz.mat');
%             save(fullfile(pathout,dataout),'Fpointsz','Fpointsy','data2');
        end

        % Callback function
        function ArrayCheckBoxValueChanged(app, event)
            if(app.ArrayCheckBox.Value)
                app.ARRAYELEMENTSPanel.Visible = true;
            else
                app.ARRAYELEMENTSPanel.Visible = false;
            end
        end

        % Value changed function: DiameterEditField
        function DiameterEditFieldValueChanged(app, event)
            app.x_size = app.DiameterEditField.Value;
        end

        % Value changed function: recEditField
        function recEditFieldValueChanged(app, event)
            app.x_size = app.recEditField.Value;
        end

        % Callback function
        function MeshSizeEditFieldValueChanged(app, event)
            app.mesh_size = app.MeshSizeEditField.Value;
        end

        % Value changed function: yEditField
        function yEditFieldValueChanged(app, event)
            app.y_size = app.yEditField.Value;
        end

        % Value changed function: squEditField
        function squEditFieldValueChanged(app, event)
            app.x_size = app.squEditField.Value;
        end

        % Value changed function: noofelementsEditField
        function noofelementsEditFieldValueChanged(app, event)
            app.array_elements = app.noofelementsEditField.Value;
        end

        % Value changed function: TransducerShapeDropDown
        function TransducerShapeDropDownValueChanged(app, event)
            app.shape = app.TransducerShapeDropDown.Value;
            switch app.shape
                case {'Square'}
                    app.SQUAREPanel.Visible = true;
                    app.CIRCLEPanel.Visible = false;
                    app.RECTANGLEPanel.Visible = false;
                    app.TRIANGLEPanel.Visible = false;
                case {'Circle'}
                    app.CIRCLEPanel.Visible = true;
                    app.RECTANGLEPanel.Visible = false;
                    app.SQUAREPanel.Visible = false;
                    app.TRIANGLEPanel.Visible = false;
                case {'Rectangle'}
                    app.RECTANGLEPanel.Visible = true;
                    app.SQUAREPanel.Visible = false;
                    app.CIRCLEPanel.Visible = false;
                    app.TRIANGLEPanel.Visible = false;
                case {'Triangle'}
                    app.TRIANGLEPanel.Visible = true;
                    app.CIRCLEPanel.Visible = false;
                    app.SQUAREPanel.Visible = false;
                    app.RECTANGLEPanel.Visible = false;
            end
        end

        % Value changed function: x1EditField
        function x1EditFieldValueChanged(app, event)
            app.vertex(1,1) = app.x1EditField.Value;
        end

        % Value changed function: y1EditField
        function y1EditFieldValueChanged(app, event)
            app.vertex(1,2) = app.y1EditField.Value;
        end

        % Value changed function: x2EditField
        function x2EditFieldValueChanged(app, event)
            app.vertex(2,1) = app.x2EditField.Value;
        end

        % Value changed function: y2EditField
        function y2EditFieldValueChanged(app, event)
            app.vertex(2,2) = app.y2EditField.Value;
        end

        % Value changed function: x3EditField
        function x3EditFieldValueChanged(app, event)
            app.vertex(3,1) = app.x3EditField.Value;
        end

        % Value changed function: y3EditField
        function y3EditFieldValueChanged(app, event)
            app.vertex(3,2) = app.y3EditField.Value;
        end

        % Callback function
        function FocalCheckBoxValueChanged(app, event)
            if(app.FocalCheckBox.Value)
                app.FOCALSIZEPanel.Visible = true;
            else
                app.FOCALSIZEPanel.Visible = false;
            end
        end

        % Value changed function: LengthEditField
        function LengthEditFieldValueChanged(app, event)
            app.focal = app.LengthEditField.Value;
        end

        % Value changed function: FrequencyValueEditField
        function FrequencyValueEditFieldValueChanged(app, event)
            app.frequency = app.FrequencyValueEditField.Value*1e3;
        end

        % Value changed function: UsageDropDown
        function UsageDropDownValueChanged(app, event)
            switch app.UsageDropDown.Value
                case {'Water'}
                    app.speed = 1530;
                case {'Air'}
                    app.speed = 340;
                case {'Tissue'}
                    app.speed = 1540;
            end
        end

        % Value changed function: ArrayCheckBox
        function ArrayCheckBoxValueChanged2(app, event)
            if(app.ArrayCheckBox.Value)
                app.ARRAYELEMENTSPanel.Visible = true;
            else
                app.ARRAYELEMENTSPanel.Visible = false;
            end
        end

        % Value changed function: FocalCheckBox
        function FocalCheckBoxValueChanged2(app, event)
            if(app.FocalCheckBox.Value)
                app.FOCALSIZEPanel.Visible = true;
            else
                app.FOCALSIZEPanel.Visible = false;
            end
        end

        % Value changed function: PhaseAngleDropDown
        function PhaseAngleDropDownValueChanged(app, event)
            switch(app.PhaseAngleDropDown.Value)
                case 'On Axis'
                    app.phase = 1;
                case '+15 Degrees Off Axis'
                    app.phase = 2;
                case '-15 Degrees Off Axis'
                    app.phase = 3;
            end
        end

        % Button pushed function: ShowAxesButton
        function ShowAxesButtonPushed(app, event)
            status = app.transducer_design.Visible;
            app.transducer_design.Visible = ~status;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TransducerDesignSettingsPanel = uipanel(app.UIFigure);
            app.TransducerDesignSettingsPanel.Title = 'Transducer Design Settings ';
            app.TransducerShapeDropDownLabel = uilabel(app.TransducerDesignSettingsPanel);
            app.TransducerShapeDropDownLabel.Text = 'Transducer Shape';
            app.TransducerShapeDropDown = uidropdown(app.TransducerDesignSettingsPanel);
            app.TransducerShapeDropDown.Items = {'Square', 'Triangle', 'Circle', 'Rectangle'};
            app.TransducerShapeDropDown.ValueChangedFcn = createCallbackFcn(app, @TransducerShapeDropDownValueChanged, true);
            app.TransducerShapeDropDown.Value = 'Square';
            app.SimulateButton = uibutton(app.TransducerDesignSettingsPanel, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.TRIANGLEPanel = uipanel(app.TransducerDesignSettingsPanel);
            app.TRIANGLEPanel.Title = 'TRIANGLE';
            app.TRIANGLEPanel.Visible = 'off';
            app.x1EditFieldLabel = uilabel(app.TRIANGLEPanel);
            app.x1EditFieldLabel.Text = 'x1';
            app.x1EditField = uieditfield(app.TRIANGLEPanel, 'numeric');
            app.x1EditField.ValueChangedFcn = createCallbackFcn(app, @x1EditFieldValueChanged, true);
            app.y1EditFieldLabel = uilabel(app.TRIANGLEPanel);
            app.y1EditFieldLabel.Text = 'y1';
            app.y1EditField = uieditfield(app.TRIANGLEPanel, 'numeric');
            app.y1EditField.ValueChangedFcn = createCallbackFcn(app, @y1EditFieldValueChanged, true);
            app.x2EditFieldLabel = uilabel(app.TRIANGLEPanel);
            app.x2EditFieldLabel.Text = 'x2';
            app.x2EditField = uieditfield(app.TRIANGLEPanel, 'numeric');
            app.x2EditField.ValueChangedFcn = createCallbackFcn(app, @x2EditFieldValueChanged, true);
            app.y2EditFieldLabel = uilabel(app.TRIANGLEPanel);
            app.y2EditFieldLabel.Text = 'y2';
            app.y2EditField = uieditfield(app.TRIANGLEPanel, 'numeric');
            app.y2EditField.ValueChangedFcn = createCallbackFcn(app, @y2EditFieldValueChanged, true);
            app.x3EditFieldLabel = uilabel(app.TRIANGLEPanel);
            app.x3EditFieldLabel.Text = 'x3';
            app.x3EditField = uieditfield(app.TRIANGLEPanel, 'numeric');
            app.x3EditField.ValueChangedFcn = createCallbackFcn(app, @x3EditFieldValueChanged, true);
            app.y3EditFieldLabel = uilabel(app.TRIANGLEPanel);
            app.y3EditFieldLabel.Text = 'y3';
            app.y3EditField = uieditfield(app.TRIANGLEPanel, 'numeric');
            app.y3EditField.ValueChangedFcn = createCallbackFcn(app, @y3EditFieldValueChanged, true);
            app.Vertex1Label = uilabel(app.TRIANGLEPanel);
            app.Vertex1Label.Text = 'Vertex 1';
            app.Vertex2Label = uilabel(app.TRIANGLEPanel);
            app.Vertex2Label.Text = 'Vertex 2';
            app.Vertex3Label = uilabel(app.TRIANGLEPanel);
            app.Vertex3Label.Text = 'Vertex 3';
            app.ARRAYELEMENTSPanel = uipanel(app.TransducerDesignSettingsPanel);
            app.ARRAYELEMENTSPanel.Title = 'ARRAY ELEMENTS';
            app.ARRAYELEMENTSPanel.Visible = 'off';
            app.noofelementsEditFieldLabel = uilabel(app.ARRAYELEMENTSPanel);
            app.noofelementsEditFieldLabel.Text = 'no. of  elements';
            app.noofelementsEditField = uieditfield(app.ARRAYELEMENTSPanel, 'numeric');
            app.noofelementsEditField.ValueChangedFcn = createCallbackFcn(app, @noofelementsEditFieldValueChanged, true);
            app.LinearCheckBox = uicheckbox(app.ARRAYELEMENTSPanel);
            app.LinearCheckBox.Text = 'Linear ';
            app.MatrixCheckBox = uicheckbox(app.ARRAYELEMENTSPanel);
            app.MatrixCheckBox.Text = 'Matrix';
            app.FOCALSIZEPanel = uipanel(app.TransducerDesignSettingsPanel);
            app.FOCALSIZEPanel.Title = 'FOCAL SIZE';
            app.FOCALSIZEPanel.Visible = 'off';
            app.LengthEditFieldLabel = uilabel(app.FOCALSIZEPanel);
            app.LengthEditFieldLabel.Text = 'Length';
            app.LengthEditField = uieditfield(app.FOCALSIZEPanel, 'numeric');
            app.LengthEditField.ValueChangedFcn = createCallbackFcn(app, @LengthEditFieldValueChanged, true);
            app.DCheckBox = uicheckbox(app.FOCALSIZEPanel);
            app.DCheckBox.Text = '1D';
            app.DCheckBox_2 = uicheckbox(app.FOCALSIZEPanel);
            app.DCheckBox_2.Text = '2D';
            app.CIRCLEPanel = uipanel(app.TransducerDesignSettingsPanel);
            app.CIRCLEPanel.Title = 'CIRCLE';
            app.CIRCLEPanel.Visible = 'off';
            app.DiameterEditFieldLabel = uilabel(app.CIRCLEPanel);
            app.DiameterEditFieldLabel.Text = 'Diameter';
            app.DiameterEditField = uieditfield(app.CIRCLEPanel, 'numeric');
            app.DiameterEditField.ValueChangedFcn = createCallbackFcn(app, @DiameterEditFieldValueChanged, true);
            app.RECTANGLEPanel = uipanel(app.TransducerDesignSettingsPanel);
            app.RECTANGLEPanel.Title = 'RECTANGLE';
            app.RECTANGLEPanel.Visible = 'off';
            app.xEditField_3Label = uilabel(app.RECTANGLEPanel);
            app.xEditField_3Label.Text = 'x';
            app.recEditField = uieditfield(app.RECTANGLEPanel, 'numeric');
            app.recEditField.ValueChangedFcn = createCallbackFcn(app, @recEditFieldValueChanged, true);
            app.yEditFieldLabel = uilabel(app.RECTANGLEPanel);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.RECTANGLEPanel, 'numeric');
            app.yEditField.ValueChangedFcn = createCallbackFcn(app, @yEditFieldValueChanged, true);
            app.SQUAREPanel = uipanel(app.TransducerDesignSettingsPanel);
            app.SQUAREPanel.Title = 'SQUARE';
            app.xEditField_2Label = uilabel(app.SQUAREPanel);
            app.xEditField_2Label.Text = 'x';
            app.squEditField = uieditfield(app.SQUAREPanel, 'numeric');
            app.squEditField.ValueChangedFcn = createCallbackFcn(app, @squEditFieldValueChanged, true);
            app.FrequencyValueEditFieldLabel = uilabel(app.TransducerDesignSettingsPanel);
            app.FrequencyValueEditFieldLabel.Text = 'Frequency Value';
            app.FrequencyValueEditField = uieditfield(app.TransducerDesignSettingsPanel, 'numeric');
            app.FrequencyValueEditField.ValueChangedFcn = createCallbackFcn(app, @FrequencyValueEditFieldValueChanged, true);
            app.kHzLabel = uilabel(app.TransducerDesignSettingsPanel);
            app.kHzLabel.Text = 'kHz';
            app.UsageDropDownLabel = uilabel(app.TransducerDesignSettingsPanel);
            app.UsageDropDownLabel.Text = 'Usage';
            app.UsageDropDown = uidropdown(app.TransducerDesignSettingsPanel);
            app.UsageDropDown.Items = {'Water', 'Air', 'Tissue', ''};
            app.UsageDropDown.ValueChangedFcn = createCallbackFcn(app, @UsageDropDownValueChanged, true);
            app.UsageDropDown.Value = 'Water';
            app.STEP1ChooseaTransducerShapeLabel = uilabel(app.TransducerDesignSettingsPanel);
            app.STEP1ChooseaTransducerShapeLabel.Text = 'STEP 1 - Choose a Transducer Shape';
            app.STEP2ChooseSizeofTransducerLabel = uilabel(app.TransducerDesignSettingsPanel);
            app.STEP2ChooseSizeofTransducerLabel.Text = 'STEP 2 - Choose Size of Transducer';
            app.ArrayCheckBox = uicheckbox(app.TransducerDesignSettingsPanel);
            app.ArrayCheckBox.ValueChangedFcn = createCallbackFcn(app, @ArrayCheckBoxValueChanged2, true);
            app.ArrayCheckBox.Text = 'Array';
            app.FocalCheckBox = uicheckbox(app.TransducerDesignSettingsPanel);
            app.FocalCheckBox.ValueChangedFcn = createCallbackFcn(app, @FocalCheckBoxValueChanged2, true);
            app.FocalCheckBox.Text = 'Focal';
            app.STEP3ChooseaFrequencyLabel = uilabel(app.TransducerDesignSettingsPanel);
            app.STEP3ChooseaFrequencyLabel.Text = 'STEP 3 - Choose a Frequency';
            app.STEP4ChooseaPhaseAngleLabel = uilabel(app.TransducerDesignSettingsPanel);
            app.STEP4ChooseaPhaseAngleLabel.Text = 'STEP 4 - Choose a Phase Angle';
            app.PhaseAngleDropDownLabel = uilabel(app.TransducerDesignSettingsPanel);
            app.PhaseAngleDropDownLabel.Text = 'Phase Angle';
            app.PhaseAngleDropDown = uidropdown(app.TransducerDesignSettingsPanel);
            app.PhaseAngleDropDown.Items = {'On Axis', '+15 Degrees Off Axis', '-15 Degrees Off Axis'};
            app.PhaseAngleDropDown.ValueChangedFcn = createCallbackFcn(app, @PhaseAngleDropDownValueChanged, true);
            app.PhaseAngleDropDown.Value = 'On Axis';
            app.Label = uilabel(app.TransducerDesignSettingsPanel);
            app.Label.Text = '';
            app.TransducerDesignLabel = uilabel(app.UIFigure);
            app.TransducerDesignLabel.Text = 'Transducer Design';
            app.FocalTransducerDesignLabel = uilabel(app.UIFigure);
            app.FocalTransducerDesignLabel.Visible = 'off';
            app.FocalTransducerDesignLabel.Text = 'Focal Transducer Design';
            app.Panel = uipanel(app.UIFigure);
            app.ShowAxesButton = uibutton(app.Panel, 'push');
            app.ShowAxesButton.ButtonPushedFcn = createCallbackFcn(app, @ShowAxesButtonPushed, true);
            app.ShowAxesButton.Text = 'Show Axes';
            app.transducer_design = uiaxes(app.Panel);
            title(app.transducer_design, {''; ''})
            xlabel(app.transducer_design, 'X')
            ylabel(app.transducer_design, 'Y')
            zlabel(app.transducer_design, 'Z')
            app.transducer_design.Visible = 'off';
            app.FullBeamSimulationLabel = uilabel(app.UIFigure);
            app.FullBeamSimulationLabel.Text = 'Full Beam Simulation';
            app.fullbeam_simulation = uiaxes(app.UIFigure);
            xlabel(app.fullbeam_simulation, 'X')
            ylabel(app.fullbeam_simulation, 'Y')
            zlabel(app.fullbeam_simulation, 'Z')
            app.fullbeam_simulation.Tag = 'fullbeam_simulation';
            app.focal_transducer_design = uiaxes(app.UIFigure);
            title(app.focal_transducer_design, {'Focal Transducer Design'; ''})
            xlabel(app.focal_transducer_design, 'X')
            ylabel(app.focal_transducer_design, 'Y')
            zlabel(app.focal_transducer_design, 'Z')
            app.focal_transducer_design.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BeamPrediction_AfterEval

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end